classdef PID_Cost < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CostFunctionsPIDTuningbyPSarhadiUIFigure  matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        Image4                       matlab.ui.control.Image
        Image3                       matlab.ui.control.Image
        Image2                       matlab.ui.control.Image
        Image                        matlab.ui.control.Image
        KdSlider                     matlab.ui.control.Slider
        KdSliderLabel                matlab.ui.control.Label
        KiSlider                     matlab.ui.control.Slider
        KiSliderLabel                matlab.ui.control.Label
        KpSlider                     matlab.ui.control.Slider
        KpSliderLabel                matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        CostFunctionsPanel           matlab.ui.container.Panel
        ControlRateEditField         matlab.ui.control.NumericEditField
        ControlRateEditFieldLabel    matlab.ui.control.Label
        ControlEffortEditField       matlab.ui.control.NumericEditField
        ControlEffortEditFieldLabel  matlab.ui.control.Label
        ITAEEditField                matlab.ui.control.NumericEditField
        ITAEEditFieldLabel           matlab.ui.control.Label
        ISEEditField                 matlab.ui.control.NumericEditField
        ISEEditFieldLabel            matlab.ui.control.Label
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: KpSlider
        function KpSliderValueChanged(app, event)
            Kp = app.KpSlider.Value;
            Ki = app.KiSlider.Value;
            Kd = app.KdSlider.Value;

            s = tf('s');
            
            sys = 7.21*(s+0.526)*s/((s+1.6)*(s-1.48)*(s-0.023));
            Gac = 1/(0.1*s+1);
            Gin = feedback(Gac*sys,Kd);
            Gol = (Kp+Ki/s)*Gin*1/s;
            Gcl = feedback(Gol,1);
            
            Ts = 0.01;
            time = 0:Ts:20;
            r = ones(size(time));
            y = lsim(Gcl,r,time);
            
            
            L = 1 + Kd*Gac*sys + (Kp+Ki/s)*Gac*sys*1/s;
            Gclu = minreal(((Kp+Ki/s)*Gac)/L);
            
            u = lsim(Gclu,r,time);


            plot(app.UIAxes,time,r,'r:',time,y,'b')
            app.UIAxes.XLabel.String = 'Time (s)';
            app.UIAxes.YLabel.String = '\theta (deg)';
            app.UIAxes.Title.String = '';


            plot(app.UIAxes2,time,u,'b')
            app.UIAxes2.XLabel.String = 'Time (s)';
            app.UIAxes2.YLabel.String = 'u (deg)';
            app.UIAxes2.Title.String = '';
            
            e = r-y';
            ISE = sum(e.^2);
            ITAE = sum(time.*abs(e));
            
            CE = sum(abs(u));
            CR = sum(abs(diff(u)/Ts));
            
                        
            app.ISEEditField.Value = ISE;
            app.ITAEEditField.Value = ITAE;
            app.ControlEffortEditField.Value = CE;
            app.ControlRateEditField.Value = CR;


        end

        % Value changed function: KiSlider
        function KiSliderValueChanged(app, event)
            Kp = app.KpSlider.Value;
            Ki = app.KiSlider.Value;
            Kd = app.KdSlider.Value;

            s = tf('s');
            
            sys = 7.21*(s+0.526)*s/((s+1.6)*(s-1.48)*(s-0.023));
            Gac = 1/(0.1*s+1);
            Gin = feedback(Gac*sys,Kd);
            Gol = (Kp+Ki/s)*Gin*1/s;
            Gcl = feedback(Gol,1);
            
            Ts = 0.01;
            time = 0:Ts:20;
            r = ones(size(time));
            y = lsim(Gcl,r,time);
            
            
            L = 1 + Kd*Gac*sys + (Kp+Ki/s)*Gac*sys*1/s;
            Gclu = minreal(((Kp+Ki/s)*Gac)/L);
            
            u = lsim(Gclu,r,time);


            plot(app.UIAxes,time,r,'r:',time,y,'b')
            app.UIAxes.XLabel.String = 'Time (s)';
            app.UIAxes.YLabel.String = '\theta (deg)';
            app.UIAxes.Title.String = '';


            plot(app.UIAxes2,time,u,'b')
            app.UIAxes2.XLabel.String = 'Time (s)';
            app.UIAxes2.YLabel.String = 'u (deg)';
            app.UIAxes2.Title.String = '';
            
            e = r-y';
            ISE = sum(e.^2);
            ITAE = sum(time.*abs(e));
            
            CE = sum(abs(u));
            CR = sum(abs(diff(u)/Ts));
            
                        
            app.ISEEditField.Value = ISE;
            app.ITAEEditField.Value = ITAE;
            app.ControlEffortEditField.Value = CE;
            app.ControlRateEditField.Value = CR;

        end

        % Value changed function: KdSlider
        function KdSliderValueChanged(app, event)
            Kp = app.KpSlider.Value;
            Ki = app.KiSlider.Value;
            Kd = app.KdSlider.Value;

            s = tf('s');
            
            sys = 7.21*(s+0.526)*s/((s+1.6)*(s-1.48)*(s-0.023));
            Gac = 1/(0.1*s+1);
            Gin = feedback(Gac*sys,Kd);
            Gol = (Kp+Ki/s)*Gin*1/s;
            Gcl = feedback(Gol,1);
            
            Ts = 0.01;
            time = 0:Ts:20;
            r = ones(size(time));
            y = lsim(Gcl,r,time);
            
            
            L = 1 + Kd*Gac*sys + (Kp+Ki/s)*Gac*sys*1/s;
            Gclu = minreal(((Kp+Ki/s)*Gac)/L);
            
            u = lsim(Gclu,r,time);


            plot(app.UIAxes,time,r,'r:',time,y,'b')
            app.UIAxes.XLabel.String = 'Time (s)';
            app.UIAxes.YLabel.String = '\theta (deg)';
            app.UIAxes.Title.String = '';


            plot(app.UIAxes2,time,u,'b')
            app.UIAxes2.XLabel.String = 'Time (s)';
            app.UIAxes2.YLabel.String = 'u (deg)';
            app.UIAxes2.Title.String = '';
            
            e = r-y';
            ISE = sum(e.^2);
            ITAE = sum(time.*abs(e));
            
            CE = sum(abs(u));
            CR = sum(abs(diff(u)/Ts));
            
                        
            app.ISEEditField.Value = ISE;
            app.ITAEEditField.Value = ITAE;
            app.ControlEffortEditField.Value = CE;
            app.ControlRateEditField.Value = CR;

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.CostFunctionsPIDTuningbyPSarhadiUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {521, 521};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {301, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CostFunctionsPIDTuningbyPSarhadiUIFigure = uifigure('Visible', 'off');
            app.CostFunctionsPIDTuningbyPSarhadiUIFigure.Name = 'Cost Functions & PID Tuning (by P Sarhadi)';
            app.CostFunctionsPIDTuningbyPSarhadiUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.CostFunctionsPIDTuningbyPSarhadiUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.KpSliderLabel = uilabel(app.LeftPanel);
            app.KpSliderLabel.Text = 'Kp';
            app.KpSlider = uislider(app.LeftPanel);
            app.KpSlider.ValueChangedFcn = createCallbackFcn(app, @KpSliderValueChanged, true);
            app.KiSliderLabel = uilabel(app.LeftPanel);
            app.KiSliderLabel.Text = 'Ki';
            app.KiSlider = uislider(app.LeftPanel);
            app.KiSlider.ValueChangedFcn = createCallbackFcn(app, @KiSliderValueChanged, true);
            app.KdSliderLabel = uilabel(app.LeftPanel);
            app.KdSliderLabel.Text = 'Kd';
            app.KdSlider = uislider(app.LeftPanel);
            app.KdSlider.ValueChangedFcn = createCallbackFcn(app, @KdSliderValueChanged, true);
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'PID.jpg';
            app.Image2 = uiimage(app.LeftPanel);
            app.Image2.ImageSource = 'Vanguard.jpg';
            app.Image3 = uiimage(app.LeftPanel);
            app.Image3.ImageSource = 'Costs.jpg';
            app.Image4 = uiimage(app.LeftPanel);
            app.Image4.ImageSource = 'TF.jpg';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.CostFunctionsPanel = uipanel(app.RightPanel);
            app.CostFunctionsPanel.Title = 'Cost Functions';
            app.ISEEditFieldLabel = uilabel(app.CostFunctionsPanel);
            app.ISEEditFieldLabel.Text = 'ISE';
            app.ISEEditField = uieditfield(app.CostFunctionsPanel, 'numeric');
            app.ITAEEditFieldLabel = uilabel(app.CostFunctionsPanel);
            app.ITAEEditFieldLabel.Text = 'ITAE';
            app.ITAEEditField = uieditfield(app.CostFunctionsPanel, 'numeric');
            app.ControlEffortEditFieldLabel = uilabel(app.CostFunctionsPanel);
            app.ControlEffortEditFieldLabel.Text = 'Control Effort';
            app.ControlEffortEditField = uieditfield(app.CostFunctionsPanel, 'numeric');
            app.ControlRateEditFieldLabel = uilabel(app.CostFunctionsPanel);
            app.ControlRateEditFieldLabel.Text = 'Control Rate';
            app.ControlRateEditField = uieditfield(app.CostFunctionsPanel, 'numeric');
            % Show the figure after all components are created
            app.CostFunctionsPIDTuningbyPSarhadiUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PID_Cost

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CostFunctionsPIDTuningbyPSarhadiUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CostFunctionsPIDTuningbyPSarhadiUIFigure)
        end
    end
end